classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Urban5GRXUIFigure              matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout9                    matlab.ui.container.GridLayout
        ConnectionAnimationCheckBox    matlab.ui.control.CheckBox
        SiteViewerAnimationCheckBox    matlab.ui.control.CheckBox
        NetworkCellInfoFileCheckBox    matlab.ui.control.CheckBox
        ForceInputCoordinatesCheckBox  matlab.ui.control.CheckBox
        DeploymentPanel                matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        UMIBlindCheckBox               matlab.ui.control.CheckBox
        UMIHotspotCheckBox             matlab.ui.control.CheckBox
        UMIGridCheckBox                matlab.ui.control.CheckBox
        UMACheckBox                    matlab.ui.control.CheckBox
        MobilityAnalysisLabel          matlab.ui.control.Label
        GridLayout7                    matlab.ui.container.GridLayout
        step                           matlab.ui.control.NumericEditField
        period                         matlab.ui.control.NumericEditField
        StepsPanel                     matlab.ui.container.Panel
        TotalTimesPanel                matlab.ui.container.Panel
        SimulationRxPanel              matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        speed                          matlab.ui.control.NumericEditField
        FileLabel                      matlab.ui.control.Label
        fileName                       matlab.ui.control.Label
        SpeedPanel                     matlab.ui.container.Panel
        duration                       matlab.ui.control.NumericEditField
        DurationPanel                  matlab.ui.container.Panel
        distance                       matlab.ui.control.NumericEditField
        DistancePanel                  matlab.ui.container.Panel
        CSVXLXSSUMOButton              matlab.ui.control.Button
        MiscPanel                      matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        ModelcharacteristicsButton     matlab.ui.control.StateButton
        UMiIntersitedistmPanel         matlab.ui.container.Panel
        GridLayout10                   matlab.ui.container.GridLayout
        umi_coverage_isd               matlab.ui.control.NumericEditField
        PropagationmodelButtonGroup    matlab.ui.container.ButtonGroup
        FogCheckBox                    matlab.ui.control.CheckBox
        GasCheckBox                    matlab.ui.control.CheckBox
        RainCheckBox                   matlab.ui.control.CheckBox
        ray_tracing                    matlab.ui.control.RadioButton
        longley_rice                   matlab.ui.control.RadioButton
        STARTButton                    matlab.ui.control.Button
        CompaniesandusersPanel         matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        companySpain                   matlab.ui.control.DropDown
        companyUSA                     matlab.ui.control.DropDown
        companyAustralia               matlab.ui.control.DropDown
        country                        matlab.ui.control.DropDown
        individual_bw                  matlab.ui.control.NumericEditField
        RandomDistributionofReceiversCheckBox  matlab.ui.control.CheckBox
        IndividualBWMHzPanel           matlab.ui.container.Panel
        number_of_receivers            matlab.ui.control.NumericEditField
        NumberofreceiversPanel         matlab.ui.container.Panel
        filter_by_company              matlab.ui.control.CheckBox
        companyUK                      matlab.ui.control.DropDown
        MapandmodePanel                matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        download_map_file              matlab.ui.control.CheckBox
        max_attempts                   matlab.ui.control.NumericEditField
        MaxattemptsforcoveragemodeEditFieldLabel  matlab.ui.control.Label
        ModeButtonGroup                matlab.ui.container.ButtonGroup
        coverage                       matlab.ui.control.RadioButton
        capacity                       matlab.ui.control.RadioButton
        maximum_longitude              matlab.ui.control.NumericEditField
        MaximumLongitudeLabel          matlab.ui.control.Label
        minimum_longitude              matlab.ui.control.NumericEditField
        MinimumLongitudeLabel          matlab.ui.control.Label
        maximum_latitude               matlab.ui.control.NumericEditField
        MaximumLatitudeLabel           matlab.ui.control.Label
        minimum_latitude               matlab.ui.control.NumericEditField
        MinimumLatitudeLabel           matlab.ui.control.Label
        TXmodelsPanel                  matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        OptimizationButton             matlab.ui.control.StateButton
        NumFreqLabel                   matlab.ui.control.Label
        MaxFreqLabel                   matlab.ui.control.Label
        MinFreqLabel                   matlab.ui.control.Label
        PowerLabel                     matlab.ui.control.Label
        umi_blind_freq_number          matlab.ui.control.NumericEditField
        umi_blind_max_freq             matlab.ui.control.NumericEditField
        umi_hotspot_freq_number        matlab.ui.control.NumericEditField
        umi_hotspot_max_freq           matlab.ui.control.NumericEditField
        umi_coverage_freq_number       matlab.ui.control.NumericEditField
        umi_coverage_max_freq          matlab.ui.control.NumericEditField
        uma_freq_number                matlab.ui.control.NumericEditField
        uma_max_freq                   matlab.ui.control.NumericEditField
        umi_coverage_min_freq          matlab.ui.control.NumericEditField
        UMiBlindCoverageLabel          matlab.ui.control.Label
        umi_blind_min_freq             matlab.ui.control.NumericEditField
        umi_blind_power                matlab.ui.control.NumericEditField
        Label_4                        matlab.ui.control.Label
        UMiHotspotLabel                matlab.ui.control.Label
        UMiGridLabel                   matlab.ui.control.Label
        umi_hotspot_power              matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        umi_hotspot_min_freq           matlab.ui.control.NumericEditField
        umi_coverage_power             matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        uma_power                      matlab.ui.control.NumericEditField
        uma_min_freq                   matlab.ui.control.NumericEditField
        UMaLabel                       matlab.ui.control.Label
    end

    
    properties (Access = public)
        Temperature = 15;
        AirPressure = 7.5;
        WaterDensity = 1.013e5;
        RainRate = 16;

        Raytracing_method = "SBR (recommended)";
        Angular_separation = "Medium";
        Number_reflections = 2;
        Buildings_material = "Brick";
        Terrain_material = "Concrete";

        Check_umiblind_opt = false;
        Check_freq_opt = false;
        Check_cellangle_opt = false;
        Opt_generations = 100;
        Opt_population = 30;
        Square_edge_umi_opt = 0.01;
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Urban5GRXUIFigure.WindowState = 'maximized';
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            addpath('entities');
            parameters = input_parameters();
            
            parameters.uma_min_freq = app.uma_min_freq.Value;
            parameters.uma_max_freq = app.uma_max_freq.Value;
            parameters.uma_freq_number = app.uma_freq_number.Value;
            parameters.uma_power = app.uma_power.Value;

            parameters.umi_coverage_power = app.umi_coverage_power.Value;
            parameters.umi_coverage_min_freq = app.umi_coverage_min_freq.Value;
            parameters.umi_coverage_max_freq = app.umi_coverage_max_freq.Value;
            parameters.umi_coverage_freq_number = app.umi_coverage_freq_number.Value;

            parameters.umi_hotspot_power = app.umi_hotspot_power.Value;
            parameters.umi_hotspot_min_freq = app.umi_hotspot_min_freq.Value;
            parameters.umi_hotspot_max_freq = app.umi_hotspot_max_freq.Value;
            parameters.umi_hotspot_freq_number = app.umi_hotspot_freq_number.Value;

            parameters.umi_blind_power = app.umi_blind_power.Value;
            parameters.umi_blind_min_freq = app.umi_blind_min_freq.Value;
            parameters.umi_blind_max_freq = app.umi_blind_max_freq.Value;
            parameters.umi_blind_freq_number = app.umi_blind_freq_number.Value;
            
            parameters.minimum_latitude = app.minimum_latitude.Value;
            parameters.maximum_latitude = app.maximum_latitude.Value;
            parameters.minimum_longitude = app.minimum_longitude.Value;
            parameters.maximum_longitude = app.maximum_longitude.Value;
            parameters.capacity = app.capacity.Value;
            parameters.coverage = app.coverage.Value;
            parameters.max_attempts = app.max_attempts.Value;
            parameters.download_map_file = app.download_map_file.Value;
            parameters.filter_by_company = app.filter_by_company.Value;
            parameters.country=app.country.Value;
            parameters.companyUSA = app.companyUSA.Value;
            parameters.companyAustralia = app.companyAustralia.Value;
            parameters.companySpain = app.companySpain.Value;
            parameters.number_of_receivers = app.number_of_receivers.Value;
            parameters.longley_rice = app.longley_rice.Value;
            parameters.ray_tracing = app.ray_tracing.Value;
            parameters.umi_coverage_isd = app.umi_coverage_isd.Value;
            parameters.individual_bw = app.individual_bw.Value;
            parameters.total_time=app.period.Value;
            parameters.step=app.step.Value;
            parameters.distance=app.distance.Value;
            parameters.duration=app.duration.Value;
            parameters.speed=app.speed.Value;
            parameters.filename=app.fileName.Text;
            parameters.uma=app.UMACheckBox.Value;
            parameters.grid=app.UMIGridCheckBox.Value;
            parameters.hotspot=app.UMIHotspotCheckBox.Value;
            parameters.blind=app.UMIBlindCheckBox.Value;
            parameters.networkcellinfo=app.NetworkCellInfoFileCheckBox.Value;
            parameters.forceCoordinates=app.ForceInputCoordinatesCheckBox.Value;
            parameters.distributionReceivers=app.RandomDistributionofReceiversCheckBox.Value;

            parameters.rain = app.RainCheckBox.Value;
            parameters.fog = app.FogCheckBox.Value;
            parameters.gas = app.GasCheckBox.Value;

            parameters.temperature = app.Temperature;
            parameters.air_pressure = app.AirPressure;
            parameters.water_density = app.WaterDensity;
            parameters.rain_rate = app.RainRate;
            
            if strcmp(app.Raytracing_method,"SBR (recommended)")
                parameters.ray_method = 'sbr';
            else
                parameters.ray_method = 'image';
            end
            
            if strcmp(app.Angular_separation,"High")
                parameters.angular_separation = 'high';
            elseif strcmp(app.Angular_separation,"Medium")
                parameters.angular_separation = 'medium';
            else
                parameters.angular_separation = 'low';
            end

            parameters.max_reflections = app.Number_reflections;

            switch app.Buildings_material
                case "Perfect reflector"
                    parameters.buildings_material = 'perfect-reflector';
                case "Brick"
                    parameters.buildings_material = 'brick';
                case "Wood"
                    parameters.buildings_material = 'wood';
                case "Glass"
                    parameters.buildings_material = 'glass';
                case "Metal"
                    parameters.buildings_material = 'metal';
                otherwise
                    parameters.buildings_material = 'concrete';
            end
            
            switch app.Terrain_material
                case "Perfect reflector"
                    parameters.terrain_material = 'perfect-reflector';
                case "Brick"
                    parameters.terrain_material = 'brick';
                case "Water"
                    parameters.terrain_material = 'water';
                case "Vegetation"
                    parameters.terrain_material = 'vegetation';
                case "Loam"
                    parameters.terrain_material = 'loam';
                otherwise
                    parameters.terrain_material = 'concrete';
            end

            parameters.siteviewer_animation = app.SiteViewerAnimationCheckBox.Value;
            parameters.connection_animation = app.ConnectionAnimationCheckBox.Value;
            
            parameters.umi_blind_position_opt = app.Check_umiblind_opt;
            parameters.freq_assignment_opt = app.Check_freq_opt;
            parameters.cellangle_opt = app.Check_cellangle_opt;
            parameters.opt_generations = app.Opt_generations;
            parameters.opt_population = app.Opt_population;
            parameters.square_edge_umiblind_opt = app.Square_edge_umi_opt;

            request(parameters);
        end

        % Button pushed function: CSVXLXSSUMOButton
        function CSVXLXSSUMOButtonPushed(app, event)
            [filename,pathname]=uigetfile({'*.xlsx;*.csv;*.sumocfg','Open file .csv, .xlsx o .sumocfg'});
            figure(app.Urban5GRXUIFigure);
            if ~strcmp(fullfile(pathname,filename),[0,'\',0])
                app.fileName.Text = fullfile(pathname,filename);
                extension = split(filename,'.');
                if strcmp(extension{2},'sumocfg')
                    app.distance.Enable = 'Off';
                    app.duration.Enable = 'Off';
                    app.speed.Enable = 'Off';
                    app.NetworkCellInfoFileCheckBox.Enable = 'Off';
                    app.number_of_receivers.Enable = 'Off';
                    app.RandomDistributionofReceiversCheckBox.Enable = 'Off';
                    app.period.Enable = 'Off';
                else
                    app.distance.Enable = 'On';
                    app.duration.Enable = 'On';
                    app.speed.Enable = 'On';
                    app.NetworkCellInfoFileCheckBox.Enable = 'On';
                    app.number_of_receivers.Enable = 'Off';
                    app.number_of_receivers.Value = 1;
                    app.RandomDistributionofReceiversCheckBox.Enable = 'Off';
                    app.period.Enable = 'On';
                end
            else
                app.fileName.Text = char([]);
                app.distance.Enable = 'On';
                app.duration.Enable = 'On';
                app.speed.Enable = 'On';
                app.NetworkCellInfoFileCheckBox.Enable = 'On';
                app.number_of_receivers.Enable = 'On';
                app.RandomDistributionofReceiversCheckBox.Enable = 'On';
                app.period.Enable = 'On';
            end
            

        end

        % Value changed function: country
        function countryValueChanged(app, event)
            value = app.country.Value;
            
            switch value
                case '214'
                    app.companyAustralia.Visible='off';
                    app.companyUK.Visible='off';
                    app.companySpain.Visible='on';
                    app.companyUSA.Visible='off';
                    app.filter_by_company.Enable='on';
                case '505'
                    app.companySpain.Visible='off';
                    app.companyUK.Visible='off';
                    app.companyAustralia.Visible='on';
                    app.companyUSA.Visible='off';
                    app.filter_by_company.Enable='on';
                case '[310,311,312,313,316]'
                    app.companySpain.Visible='off';
                    app.companyUK.Visible='off';
                    app.companyAustralia.Visible='off';
                    app.companyUSA.Visible='on';
                    app.filter_by_company.Enable='on';
                case '[234,235,730,748]'
                    app.companySpain.Visible='off';
                    app.companyUK.Visible='on';
                    app.companyAustralia.Visible='off';
                    app.companyUSA.Visible='off';
                    app.filter_by_company.Enable='on';
                otherwise
                    app.companySpain.Visible='off';
                    app.companyAustralia.Visible='off';
                    app.companyUSA.Visible='off';
                    app.companyUK.Visible='off';
                    app.filter_by_company.Value=false;
                    app.filter_by_companyValueChanged();
                    app.filter_by_company.Enable='off';
            end
        end

        % Value changed function: ModelcharacteristicsButton
        function ModelcharacteristicsButtonValueChanged(app, event)
            value = app.ModelcharacteristicsButton.Value;
            if value
                model_configuration(app,...
                    app.Temperature,...
                    app.AirPressure,...
                    app.WaterDensity,...
                    app.RainRate,...
                    app.Raytracing_method,...
                    app.Angular_separation,...
                    app.Number_reflections,...
                    app.Buildings_material,...
                    app.Terrain_material);
            end
            app.ModelcharacteristicsButton.Value = ~app.ModelcharacteristicsButton.Value;
        end

        % Value changed function: UMIGridCheckBox
        function UMIGridCheckBoxValueChanged(app, event)
            value = app.UMIGridCheckBox.Value;
            if value
                app.umi_coverage_power.Enable = 'On';
                app.umi_coverage_min_freq.Enable = 'On';
                app.umi_coverage_max_freq.Enable = 'On';
                app.umi_coverage_freq_number.Enable = 'On';
                app.umi_coverage_isd.Enable = 'On';
            else
                app.umi_coverage_power.Enable = 'Off';
                app.umi_coverage_min_freq.Enable = 'Off';
                app.umi_coverage_max_freq.Enable = 'Off';
                app.umi_coverage_freq_number.Enable = 'Off';
                app.umi_coverage_isd.Enable = 'Off';
            end
        end

        % Value changed function: UMIHotspotCheckBox
        function UMIHotspotCheckBoxValueChanged(app, event)
            value = app.UMIHotspotCheckBox.Value;
            if value
                app.umi_hotspot_power.Enable = 'On';
                app.umi_hotspot_min_freq.Enable = 'On';
                app.umi_hotspot_max_freq.Enable = 'On';
                app.umi_hotspot_freq_number.Enable = 'On';
            else
                app.umi_hotspot_power.Enable = 'Off';
                app.umi_hotspot_min_freq.Enable = 'Off';
                app.umi_hotspot_max_freq.Enable = 'Off';
                app.umi_hotspot_freq_number.Enable = 'Off';
            end
        end

        % Value changed function: UMIBlindCheckBox
        function UMIBlindCheckBoxValueChanged(app, event)
            value = app.UMIBlindCheckBox.Value;
            if value
                app.umi_blind_power.Enable = 'On';
                app.umi_blind_min_freq.Enable = 'On';
                app.umi_blind_max_freq.Enable = 'On';
                app.umi_blind_freq_number.Enable = 'On';
            else
                app.umi_blind_power.Enable = 'Off';
                app.umi_blind_min_freq.Enable = 'Off';
                app.umi_blind_max_freq.Enable = 'Off';
                app.umi_blind_freq_number.Enable = 'Off';
            end
        end

        % Value changed function: filter_by_company
        function filter_by_companyValueChanged(app, event)
            value = app.filter_by_company.Value;
            if value
                app.companyAustralia.Enable = 'On';
                app.companySpain.Enable = 'On';
                app.companyUSA.Enable = 'On';
                app.companyUK.Enable = 'On';
            else
                app.companyAustralia.Enable = 'Off';
                app.companySpain.Enable = 'Off';
                app.companyUSA.Enable = 'Off';
                app.companyUK.Enable = 'Off';
            end
        end

        % Value changed function: OptimizationButton
        function OptimizationButtonValueChanged(app, event)
            value = app.OptimizationButton.Value;
            if value
                optimization_configuration(app,...
                    app.Check_umiblind_opt,...
                    app.Check_freq_opt,...
                    app.Check_cellangle_opt,...
                    app.Opt_generations,...
                    app.Opt_population,...
                    app.Square_edge_umi_opt);
            end
            app.OptimizationButton.Value = ~app.OptimizationButton.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Urban5GRXUIFigure = uifigure('Visible', 'off');
            app.Urban5GRXUIFigure.Name = 'Urban 5GRX';
            app.GridLayout = uigridlayout(app.Urban5GRXUIFigure);
            app.TXmodelsPanel = uipanel(app.GridLayout);
            app.TXmodelsPanel.Title = 'TX models';
            app.GridLayout4 = uigridlayout(app.TXmodelsPanel);
            app.UMaLabel = uilabel(app.GridLayout4);
            app.UMaLabel.Text = 'UMa';
            app.uma_min_freq = uieditfield(app.GridLayout4, 'numeric');
            app.uma_min_freq.Limits = [1 100000000000];
            app.uma_min_freq.Value = 3700000000;
            app.uma_power = uieditfield(app.GridLayout4, 'numeric');
            app.uma_power.Value = 25;
            app.Label_2 = uilabel(app.GridLayout4);
            app.Label_2.Text = '';
            app.umi_coverage_power = uieditfield(app.GridLayout4, 'numeric');
            app.umi_coverage_power.Enable = 'off';
            app.umi_coverage_power.Value = 5;
            app.umi_hotspot_min_freq = uieditfield(app.GridLayout4, 'numeric');
            app.umi_hotspot_min_freq.Limits = [1 100000000000];
            app.umi_hotspot_min_freq.Enable = 'off';
            app.umi_hotspot_min_freq.Value = 18000000000;
            app.Label_3 = uilabel(app.GridLayout4);
            app.Label_3.Text = '';
            app.umi_hotspot_power = uieditfield(app.GridLayout4, 'numeric');
            app.umi_hotspot_power.Enable = 'off';
            app.umi_hotspot_power.Value = 5;
            app.UMiGridLabel = uilabel(app.GridLayout4);
            app.UMiGridLabel.Text = 'UMi Grid';
            app.UMiHotspotLabel = uilabel(app.GridLayout4);
            app.UMiHotspotLabel.Text = 'UMi Hotspot';
            app.Label_4 = uilabel(app.GridLayout4);
            app.Label_4.Text = '';
            app.umi_blind_power = uieditfield(app.GridLayout4, 'numeric');
            app.umi_blind_power.Enable = 'off';
            app.umi_blind_power.Value = 3;
            app.umi_blind_min_freq = uieditfield(app.GridLayout4, 'numeric');
            app.umi_blind_min_freq.Limits = [1 100000000000];
            app.umi_blind_min_freq.Enable = 'off';
            app.umi_blind_min_freq.Value = 6000000000;
            app.UMiBlindCoverageLabel = uilabel(app.GridLayout4);
            app.UMiBlindCoverageLabel.Text = 'UMi Blind Coverage';
            app.umi_coverage_min_freq = uieditfield(app.GridLayout4, 'numeric');
            app.umi_coverage_min_freq.Limits = [1 100000000000];
            app.umi_coverage_min_freq.Enable = 'off';
            app.umi_coverage_min_freq.Value = 16000000000;
            app.uma_max_freq = uieditfield(app.GridLayout4, 'numeric');
            app.uma_max_freq.Limits = [1 100000000000];
            app.uma_max_freq.Value = 3800000000;
            app.uma_freq_number = uieditfield(app.GridLayout4, 'numeric');
            app.uma_freq_number.Limits = [1 Inf];
            app.uma_freq_number.Value = 4;
            app.umi_coverage_max_freq = uieditfield(app.GridLayout4, 'numeric');
            app.umi_coverage_max_freq.Limits = [1 100000000000];
            app.umi_coverage_max_freq.Enable = 'off';
            app.umi_coverage_max_freq.Value = 17000000000;
            app.umi_coverage_freq_number = uieditfield(app.GridLayout4, 'numeric');
            app.umi_coverage_freq_number.Limits = [1 Inf];
            app.umi_coverage_freq_number.Enable = 'off';
            app.umi_coverage_freq_number.Value = 2;
            app.umi_hotspot_max_freq = uieditfield(app.GridLayout4, 'numeric');
            app.umi_hotspot_max_freq.Limits = [1 100000000000];
            app.umi_hotspot_max_freq.Enable = 'off';
            app.umi_hotspot_max_freq.Value = 19000000000;
            app.umi_hotspot_freq_number = uieditfield(app.GridLayout4, 'numeric');
            app.umi_hotspot_freq_number.Limits = [1 Inf];
            app.umi_hotspot_freq_number.Enable = 'off';
            app.umi_hotspot_freq_number.Value = 2;
            app.umi_blind_max_freq = uieditfield(app.GridLayout4, 'numeric');
            app.umi_blind_max_freq.Limits = [1 100000000000];
            app.umi_blind_max_freq.Enable = 'off';
            app.umi_blind_max_freq.Value = 7000000000;
            app.umi_blind_freq_number = uieditfield(app.GridLayout4, 'numeric');
            app.umi_blind_freq_number.Limits = [1 Inf];
            app.umi_blind_freq_number.Enable = 'off';
            app.umi_blind_freq_number.Value = 2;
            app.PowerLabel = uilabel(app.GridLayout4);
            app.PowerLabel.Text = 'Power (W)';
            app.MinFreqLabel = uilabel(app.GridLayout4);
            app.MinFreqLabel.Text = 'Minimum Frequency (Hz)';
            app.MaxFreqLabel = uilabel(app.GridLayout4);
            app.MaxFreqLabel.Text = 'Maximum Frequency (Hz)';
            app.NumFreqLabel = uilabel(app.GridLayout4);
            app.NumFreqLabel.Text = 'Number of Frequencies';
            app.OptimizationButton = uibutton(app.GridLayout4, 'state');
            app.OptimizationButton.ValueChangedFcn = createCallbackFcn(app, @OptimizationButtonValueChanged, true);
            app.OptimizationButton.Text = 'Optimization';
            app.MapandmodePanel = uipanel(app.GridLayout);
            app.MapandmodePanel.Title = 'Map and mode';
            app.GridLayout2 = uigridlayout(app.MapandmodePanel);
            app.MinimumLatitudeLabel = uilabel(app.GridLayout2);
            app.MinimumLatitudeLabel.Text = 'Minimum Latitude';
            app.minimum_latitude = uieditfield(app.GridLayout2, 'numeric');
            app.minimum_latitude.ValueDisplayFormat = '%.4f';
            app.minimum_latitude.Value = 37.1767;
            app.MaximumLatitudeLabel = uilabel(app.GridLayout2);
            app.MaximumLatitudeLabel.Text = 'Maximum Latitude';
            app.maximum_latitude = uieditfield(app.GridLayout2, 'numeric');
            app.maximum_latitude.ValueDisplayFormat = '%.4f';
            app.maximum_latitude.Value = 37.1795;
            app.MinimumLongitudeLabel = uilabel(app.GridLayout2);
            app.MinimumLongitudeLabel.Text = 'Minimum Longitude';
            app.minimum_longitude = uieditfield(app.GridLayout2, 'numeric');
            app.minimum_longitude.ValueDisplayFormat = '%.4f';
            app.minimum_longitude.Value = -3.6132;
            app.MaximumLongitudeLabel = uilabel(app.GridLayout2);
            app.MaximumLongitudeLabel.Text = 'Maximum Longitude';
            app.maximum_longitude = uieditfield(app.GridLayout2, 'numeric');
            app.maximum_longitude.ValueDisplayFormat = '%.4f';
            app.maximum_longitude.Value = -3.6062;
            app.ModeButtonGroup = uibuttongroup(app.GridLayout2);
            app.ModeButtonGroup.Title = 'Mode';
            app.capacity = uiradiobutton(app.ModeButtonGroup);
            app.capacity.Text = 'Capacity (focused on hotspots)';
            app.capacity.Value = true;
            app.coverage = uiradiobutton(app.ModeButtonGroup);
            app.coverage.Enable = 'off';
            app.coverage.Text = 'Coverage (try to cover everything)';
            app.MaxattemptsforcoveragemodeEditFieldLabel = uilabel(app.GridLayout2);
            app.MaxattemptsforcoveragemodeEditFieldLabel.Text = {'Max attempts for'; 'coverage mode'};
            app.max_attempts = uieditfield(app.GridLayout2, 'numeric');
            app.max_attempts.Limits = [-1 Inf];
            app.max_attempts.RoundFractionalValues = 'on';
            app.max_attempts.ValueDisplayFormat = '%.0f';
            app.max_attempts.Enable = 'off';
            app.max_attempts.Value = 5;
            app.download_map_file = uicheckbox(app.GridLayout2);
            app.download_map_file.Text = 'Download Map File';
            app.download_map_file.Value = true;
            app.CompaniesandusersPanel = uipanel(app.GridLayout);
            app.CompaniesandusersPanel.Title = 'Companies and users';
            app.GridLayout3 = uigridlayout(app.CompaniesandusersPanel);
            app.companyUK = uidropdown(app.GridLayout3);
            app.companyUK.Items = {'O2', 'Virgin', 'Vodafone'};
            app.companyUK.ItemsData = {'11', '31', '15'};
            app.companyUK.Enable = 'off';
            app.companyUK.Visible = 'off';
            app.companyUK.Value = '11';
            app.filter_by_company = uicheckbox(app.GridLayout3);
            app.filter_by_company.ValueChangedFcn = createCallbackFcn(app, @filter_by_companyValueChanged, true);
            app.filter_by_company.Text = 'Filter by Company';
            app.NumberofreceiversPanel = uipanel(app.GridLayout3);
            app.NumberofreceiversPanel.Title = 'Number of receivers:';
            app.number_of_receivers = uieditfield(app.GridLayout3, 'numeric');
            app.number_of_receivers.Limits = [1 15000];
            app.number_of_receivers.ValueDisplayFormat = '%.0f';
            app.number_of_receivers.Value = 15000;
            app.IndividualBWMHzPanel = uipanel(app.GridLayout3);
            app.IndividualBWMHzPanel.Title = 'Individual BW (MHz):';
            app.RandomDistributionofReceiversCheckBox = uicheckbox(app.GridLayout3);
            app.RandomDistributionofReceiversCheckBox.Text = {'Random '; 'Distribution '; 'of Receivers'};
            app.individual_bw = uieditfield(app.GridLayout3, 'numeric');
            app.individual_bw.Limits = [0 10000];
            app.individual_bw.ValueDisplayFormat = '%.0f';
            app.individual_bw.Value = 100;
            app.country = uidropdown(app.GridLayout3);
            app.country.Items = {'Australia', 'China', 'UK', 'Spain', 'USA'};
            app.country.ItemsData = {'505', '[454,455,460]', '[234,235,730,748]', '214', '[310,311,312,313,316]'};
            app.country.ValueChangedFcn = createCallbackFcn(app, @countryValueChanged, true);
            app.country.Value = '214';
            app.companyAustralia = uidropdown(app.GridLayout3);
            app.companyAustralia.Items = {'Telstra', 'Optus', 'Vodafone'};
            app.companyAustralia.ItemsData = {'1', '2', '3'};
            app.companyAustralia.Enable = 'off';
            app.companyAustralia.Visible = 'off';
            app.companyAustralia.Value = '1';
            app.companyUSA = uidropdown(app.GridLayout3);
            app.companyUSA.Items = {'AT&T', 'VZ', 'TMUS'};
            app.companyUSA.ItemsData = {'410', '012', '660', ''};
            app.companyUSA.Enable = 'off';
            app.companyUSA.Visible = 'off';
            app.companyUSA.Value = '410';
            app.companySpain = uidropdown(app.GridLayout3);
            app.companySpain.Items = {'Vodafone', 'Orange', 'Movistar'};
            app.companySpain.ItemsData = {'1', '3', '7'};
            app.companySpain.Enable = 'off';
            app.companySpain.Value = '1';
            app.STARTButton = uibutton(app.GridLayout, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START!';
            app.MiscPanel = uipanel(app.GridLayout);
            app.MiscPanel.Title = 'Misc.';
            app.GridLayout5 = uigridlayout(app.MiscPanel);
            app.PropagationmodelButtonGroup = uibuttongroup(app.GridLayout5);
            app.PropagationmodelButtonGroup.Title = 'Propagation model';
            app.longley_rice = uiradiobutton(app.PropagationmodelButtonGroup);
            app.longley_rice.Text = 'Longley-rice';
            app.longley_rice.Value = true;
            app.ray_tracing = uiradiobutton(app.PropagationmodelButtonGroup);
            app.ray_tracing.Text = 'Ray tracing';
            app.RainCheckBox = uicheckbox(app.PropagationmodelButtonGroup);
            app.RainCheckBox.Text = 'Rain';
            app.GasCheckBox = uicheckbox(app.PropagationmodelButtonGroup);
            app.GasCheckBox.Text = 'Gas';
            app.FogCheckBox = uicheckbox(app.PropagationmodelButtonGroup);
            app.FogCheckBox.Text = 'Fog';
            app.UMiIntersitedistmPanel = uipanel(app.GridLayout5);
            app.UMiIntersitedistmPanel.Title = 'UMi Intersite dist. (m)';
            app.GridLayout10 = uigridlayout(app.UMiIntersitedistmPanel);
            app.umi_coverage_isd = uieditfield(app.GridLayout10, 'numeric');
            app.umi_coverage_isd.Limits = [1 1000];
            app.umi_coverage_isd.Enable = 'off';
            app.umi_coverage_isd.Value = 300;
            app.ModelcharacteristicsButton = uibutton(app.GridLayout5, 'state');
            app.ModelcharacteristicsButton.ValueChangedFcn = createCallbackFcn(app, @ModelcharacteristicsButtonValueChanged, true);
            app.ModelcharacteristicsButton.Text = 'Model characteristics';
            app.SimulationRxPanel = uipanel(app.GridLayout);
            app.SimulationRxPanel.Title = 'Simulation Rx';
            app.GridLayout6 = uigridlayout(app.SimulationRxPanel);
            app.CSVXLXSSUMOButton = uibutton(app.GridLayout6, 'push');
            app.CSVXLXSSUMOButton.ButtonPushedFcn = createCallbackFcn(app, @CSVXLXSSUMOButtonPushed, true);
            app.CSVXLXSSUMOButton.Text = 'CSV / XLXS / SUMO';
            app.DistancePanel = uipanel(app.GridLayout6);
            app.DistancePanel.Title = 'Distance (m):';
            app.distance = uieditfield(app.GridLayout6, 'numeric');
            app.distance.Limits = [0 Inf];
            app.distance.ValueDisplayFormat = '%.0f';
            app.DurationPanel = uipanel(app.GridLayout6);
            app.DurationPanel.Title = 'Duration (s):';
            app.duration = uieditfield(app.GridLayout6, 'numeric');
            app.duration.Limits = [0 Inf];
            app.duration.ValueDisplayFormat = '%.0f';
            app.SpeedPanel = uipanel(app.GridLayout6);
            app.SpeedPanel.Title = 'Speed (m/s):';
            app.fileName = uilabel(app.GridLayout6);
            app.fileName.Text = '';
            app.FileLabel = uilabel(app.GridLayout6);
            app.FileLabel.Text = 'File: ';
            app.speed = uieditfield(app.GridLayout6, 'numeric');
            app.speed.Limits = [0 Inf];
            app.speed.ValueDisplayFormat = '%.0f';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.TotalTimesPanel = uipanel(app.GridLayout7);
            app.TotalTimesPanel.Title = 'Total Time (s):';
            app.StepsPanel = uipanel(app.GridLayout7);
            app.StepsPanel.Title = 'Step (s):';
            app.period = uieditfield(app.GridLayout7, 'numeric');
            app.period.Limits = [0 Inf];
            app.period.ValueDisplayFormat = '%.0f';
            app.period.Value = 100;
            app.step = uieditfield(app.GridLayout7, 'numeric');
            app.step.Limits = [0 Inf];
            app.step.ValueDisplayFormat = '%.0f';
            app.step.Value = 10;
            app.MobilityAnalysisLabel = uilabel(app.GridLayout);
            app.MobilityAnalysisLabel.Text = 'Mobility Analysis';
            app.DeploymentPanel = uipanel(app.GridLayout);
            app.DeploymentPanel.Title = 'Deployment';
            app.GridLayout8 = uigridlayout(app.DeploymentPanel);
            app.UMACheckBox = uicheckbox(app.GridLayout8);
            app.UMACheckBox.Enable = 'off';
            app.UMACheckBox.Text = 'UMA';
            app.UMACheckBox.Value = true;
            app.UMIGridCheckBox = uicheckbox(app.GridLayout8);
            app.UMIGridCheckBox.ValueChangedFcn = createCallbackFcn(app, @UMIGridCheckBoxValueChanged, true);
            app.UMIGridCheckBox.Text = 'UMI Grid';
            app.UMIHotspotCheckBox = uicheckbox(app.GridLayout8);
            app.UMIHotspotCheckBox.ValueChangedFcn = createCallbackFcn(app, @UMIHotspotCheckBoxValueChanged, true);
            app.UMIHotspotCheckBox.Text = 'UMI Hotspot';
            app.UMIBlindCheckBox = uicheckbox(app.GridLayout8);
            app.UMIBlindCheckBox.ValueChangedFcn = createCallbackFcn(app, @UMIBlindCheckBoxValueChanged, true);
            app.UMIBlindCheckBox.Text = 'UMI Blind';
            app.GridLayout9 = uigridlayout(app.GridLayout);
            app.ForceInputCoordinatesCheckBox = uicheckbox(app.GridLayout9);
            app.ForceInputCoordinatesCheckBox.Text = 'Force Input Coordinates';
            app.NetworkCellInfoFileCheckBox = uicheckbox(app.GridLayout9);
            app.NetworkCellInfoFileCheckBox.Text = 'Network Cell Info File';
            app.SiteViewerAnimationCheckBox = uicheckbox(app.GridLayout9);
            app.SiteViewerAnimationCheckBox.Text = 'Site Viewer Animation';
            app.SiteViewerAnimationCheckBox.Value = true;
            app.ConnectionAnimationCheckBox = uicheckbox(app.GridLayout9);
            app.ConnectionAnimationCheckBox.Text = 'Connection Animation';
            % Show the figure after all components are created
            app.Urban5GRXUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Urban5GRXUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Urban5GRXUIFigure)
        end
    end
end