classdef model_configuration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PropagationmodeloptionsUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        ENTERButton                    matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        MainModelTab                   matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        EnvironmentcharacteristicsforraytracingmodelPanel  matlab.ui.container.Panel
        GridLayout2_3                  matlab.ui.container.GridLayout
        SurfacematerialofgeographicterrainDropDown  matlab.ui.control.DropDown
        SurfacematerialofgeographicterrainDropDownLabel  matlab.ui.control.Label
        SurfacematerialofgeographicbuildingsDropDown  matlab.ui.control.DropDown
        SurfacematerialofgeographicbuildingsDropDownLabel  matlab.ui.control.Label
        MaximumnumberofpathreflectionsEditField  matlab.ui.control.NumericEditField
        MaximumnumberofpathreflectionsEditFieldLabel  matlab.ui.control.Label
        AngularseparationoflaunchedraysDropDown  matlab.ui.control.DropDown
        AngularseparationoflaunchedraysDropDownLabel  matlab.ui.control.Label
        RaytracingmethodDropDown       matlab.ui.control.DropDown
        RaytracingmethodDropDownLabel  matlab.ui.control.Label
        SecundaryModelsTab             matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        MeteorologicalcharacteristicsPanel  matlab.ui.container.Panel
        GridLayout2_2                  matlab.ui.container.GridLayout
        AirpressurePaEditField         matlab.ui.control.NumericEditField
        AirpressurePaEditFieldLabel    matlab.ui.control.Label
        WaterdensitygmEditField        matlab.ui.control.NumericEditField
        WaterdensitygmEditFieldLabel   matlab.ui.control.Label
        TemperatureCEditField          matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel     matlab.ui.control.Label
        RainratemmhEditField           matlab.ui.control.NumericEditField
        RainratemmhEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, temperature, air_pressure, water_density, rain_rate, ray_method, angular_sep, ref_num, buildings_mat, surface_mat)
            app.CallingApp = mainapp;
            if app.CallingApp.RainCheckBox.Value
                app.RainratemmhEditField.Enable = 'On';
                app.RainratemmhEditField.Value = rain_rate;
            end
            if app.CallingApp.GasCheckBox.Value
                app.TemperatureCEditField.Enable = 'On';
                app.TemperatureCEditField.Value = temperature;
                app.AirpressurePaEditField.Enable = 'On';
                app.AirpressurePaEditField.Value = air_pressure;
                app.WaterdensitygmEditField.Enable = 'On';
                app.WaterdensitygmEditField.Value = water_density;
            end
            if app.CallingApp.FogCheckBox.Value
                app.TemperatureCEditField.Enable = 'On';
                app.TemperatureCEditField.Value = temperature;
                app.WaterdensitygmEditField.Enable = 'On';
                app.WaterdensitygmEditField.Value = water_density;
            end

            if app.CallingApp.ray_tracing.Value
                app.RaytracingmethodDropDown.Enable = 'On';
                app.AngularseparationoflaunchedraysDropDown.Enable = 'On';
                app.MaximumnumberofpathreflectionsEditField.Enable = 'On';
                app.SurfacematerialofgeographicbuildingsDropDown.Enable = 'On';
                app.SurfacematerialofgeographicterrainDropDown.Enable = 'On';

                app.RaytracingmethodDropDown.Value = ray_method;
                app.AngularseparationoflaunchedraysDropDown.Value = angular_sep;
                app.MaximumnumberofpathreflectionsEditField.Value = ref_num;
                app.SurfacematerialofgeographicbuildingsDropDown.Value = buildings_mat;
                app.SurfacematerialofgeographicterrainDropDown.Value = surface_mat;
            end
        end

        % Button pushed function: ENTERButton
        function ENTERButtonPushed(app, event)
            app.CallingApp.Temperature = app.TemperatureCEditField.Value;
            app.CallingApp.AirPressure = app.AirpressurePaEditField.Value;
            app.CallingApp.WaterDensity = app.WaterdensitygmEditField.Value;
            app.CallingApp.RainRate = app.RainratemmhEditField.Value;

            app.CallingApp.Raytracing_method = app.RaytracingmethodDropDown.Value;
            app.CallingApp.Angular_separation = app.AngularseparationoflaunchedraysDropDown.Value;
            app.CallingApp.Number_reflections = round(app.MaximumnumberofpathreflectionsEditField.Value);
            app.CallingApp.Buildings_material = app.SurfacematerialofgeographicbuildingsDropDown.Value;
            app.CallingApp.Terrain_material = app.SurfacematerialofgeographicterrainDropDown.Value;
            delete(app);
        end

        % Value changed function: MaximumnumberofpathreflectionsEditField, 
        % RaytracingmethodDropDown
        function RaytracingmethodDropDownValueChanged(app, event)
            value = app.RaytracingmethodDropDown.Value;
            if strcmp(value,'Image') && app.MaximumnumberofpathreflectionsEditField.Value > 2
                app.MaximumnumberofpathreflectionsEditField.Value = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PropagationmodeloptionsUIFigure = uifigure('Visible', 'off');
            app.PropagationmodeloptionsUIFigure.Name = 'Propagation model options';
            app.GridLayout = uigridlayout(app.PropagationmodeloptionsUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.MainModelTab = uitab(app.TabGroup);
            app.MainModelTab.Title = 'Main Model';
            app.GridLayout3 = uigridlayout(app.MainModelTab);
            app.EnvironmentcharacteristicsforraytracingmodelPanel = uipanel(app.GridLayout3);
            app.EnvironmentcharacteristicsforraytracingmodelPanel.Title = 'Environment characteristics (for ray tracing model)';
            app.GridLayout2_3 = uigridlayout(app.EnvironmentcharacteristicsforraytracingmodelPanel);
            app.RaytracingmethodDropDownLabel = uilabel(app.GridLayout2_3);
            app.RaytracingmethodDropDownLabel.Text = 'Ray tracing method';
            app.RaytracingmethodDropDown = uidropdown(app.GridLayout2_3);
            app.RaytracingmethodDropDown.Items = {'SBR (recommended)', 'Image'};
            app.RaytracingmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @RaytracingmethodDropDownValueChanged, true);
            app.RaytracingmethodDropDown.Enable = 'off';
            app.RaytracingmethodDropDown.Value = 'SBR (recommended)';
            app.AngularseparationoflaunchedraysDropDownLabel = uilabel(app.GridLayout2_3);
            app.AngularseparationoflaunchedraysDropDownLabel.Text = 'Angular separation of launched rays';
            app.AngularseparationoflaunchedraysDropDown = uidropdown(app.GridLayout2_3);
            app.AngularseparationoflaunchedraysDropDown.Items = {'High', 'Medium', 'Low'};
            app.AngularseparationoflaunchedraysDropDown.Enable = 'off';
            app.AngularseparationoflaunchedraysDropDown.Value = 'Medium';
            app.MaximumnumberofpathreflectionsEditFieldLabel = uilabel(app.GridLayout2_3);
            app.MaximumnumberofpathreflectionsEditFieldLabel.Text = 'Maximum number of path reflections';
            app.MaximumnumberofpathreflectionsEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.MaximumnumberofpathreflectionsEditField.Limits = [1 10];
            app.MaximumnumberofpathreflectionsEditField.ValueChangedFcn = createCallbackFcn(app, @RaytracingmethodDropDownValueChanged, true);
            app.MaximumnumberofpathreflectionsEditField.Enable = 'off';
            app.MaximumnumberofpathreflectionsEditField.Value = 2;
            app.SurfacematerialofgeographicbuildingsDropDownLabel = uilabel(app.GridLayout2_3);
            app.SurfacematerialofgeographicbuildingsDropDownLabel.Text = 'Surface material of geographic buildings';
            app.SurfacematerialofgeographicbuildingsDropDown = uidropdown(app.GridLayout2_3);
            app.SurfacematerialofgeographicbuildingsDropDown.Items = {'Perfect reflector', 'Concrete', 'Brick', 'Wood', 'Glass', 'Metal'};
            app.SurfacematerialofgeographicbuildingsDropDown.Enable = 'off';
            app.SurfacematerialofgeographicbuildingsDropDown.Value = 'Brick';
            app.SurfacematerialofgeographicterrainDropDownLabel = uilabel(app.GridLayout2_3);
            app.SurfacematerialofgeographicterrainDropDownLabel.Text = 'Surface material of geographic terrain';
            app.SurfacematerialofgeographicterrainDropDown = uidropdown(app.GridLayout2_3);
            app.SurfacematerialofgeographicterrainDropDown.Items = {'Perfect reflector', 'Concrete', 'Brick', 'Water', 'Vegetation', 'Loam'};
            app.SurfacematerialofgeographicterrainDropDown.Enable = 'off';
            app.SurfacematerialofgeographicterrainDropDown.Value = 'Concrete';
            app.SecundaryModelsTab = uitab(app.TabGroup);
            app.SecundaryModelsTab.Title = 'Secundary Models';
            app.GridLayout2 = uigridlayout(app.SecundaryModelsTab);
            app.MeteorologicalcharacteristicsPanel = uipanel(app.GridLayout2);
            app.MeteorologicalcharacteristicsPanel.Title = 'Meteorological characteristics';
            app.GridLayout2_2 = uigridlayout(app.MeteorologicalcharacteristicsPanel);
            app.RainratemmhEditFieldLabel = uilabel(app.GridLayout2_2);
            app.RainratemmhEditFieldLabel.Text = 'Rain rate (mm/h)';
            app.RainratemmhEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.RainratemmhEditField.Limits = [0 Inf];
            app.RainratemmhEditField.Enable = 'off';
            app.RainratemmhEditField.Value = 16;
            app.TemperatureCEditFieldLabel = uilabel(app.GridLayout2_2);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (ºC)';
            app.TemperatureCEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.TemperatureCEditField.Enable = 'off';
            app.TemperatureCEditField.Value = 15;
            app.WaterdensitygmEditFieldLabel = uilabel(app.GridLayout2_2);
            app.WaterdensitygmEditFieldLabel.Text = 'Water density (g/m³)';
            app.WaterdensitygmEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.WaterdensitygmEditField.Enable = 'off';
            app.WaterdensitygmEditField.Value = 7.5;
            app.AirpressurePaEditFieldLabel = uilabel(app.GridLayout2_2);
            app.AirpressurePaEditFieldLabel.Text = 'Air pressure (Pa)';
            app.AirpressurePaEditField = uieditfield(app.GridLayout2_2, 'numeric');
            app.AirpressurePaEditField.Enable = 'off';
            app.AirpressurePaEditField.Value = 101300;
            app.ENTERButton = uibutton(app.GridLayout, 'push');
            app.ENTERButton.ButtonPushedFcn = createCallbackFcn(app, @ENTERButtonPushed, true);
            app.ENTERButton.Text = {'ENTER'; ''};
            % Show the figure after all components are created
            app.PropagationmodeloptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = model_configuration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PropagationmodeloptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PropagationmodeloptionsUIFigure)
        end
    end
end