classdef optimization_configuration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptimizationoptionsUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        ParametersandoptimizationoptionsPanel  matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        SquareedgesizeonlyforUMiBlindoptimizationEditField  matlab.ui.control.NumericEditField
        SquareedgesizeonlyforUMiBlindoptimizationEditFieldLabel  matlab.ui.control.Label
        PopulationEditField          matlab.ui.control.NumericEditField
        PopulationEditFieldLabel     matlab.ui.control.Label
        GenerationsEditField         matlab.ui.control.NumericEditField
        GenerationsEditFieldLabel    matlab.ui.control.Label
        ParameterstooptimizeLabel    matlab.ui.control.Label
        GridLayout3                  matlab.ui.container.GridLayout
        CellsectoranglesCheckBox     matlab.ui.control.CheckBox
        FrequencyassignmentCheckBox  matlab.ui.control.CheckBox
        UMiBlindCoveragepositionsCheckBox  matlab.ui.control.CheckBox
        ENTERButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, check_umi, check_freq, check_cell_angle, generations, population, square_edge_umi_searching)
            app.CallingApp = mainapp;
            if app.CallingApp.UMIBlindCheckBox.Value
                app.UMiBlindCoveragepositionsCheckBox.Enable = 'On';
                app.UMiBlindCoveragepositionsCheckBox.Value = check_umi;
                if check_umi
                    app.SquareedgesizeonlyforUMiBlindoptimizationEditField.Enable = 'On';
                    app.SquareedgesizeonlyforUMiBlindoptimizationEditField.Value = square_edge_umi_searching;
                end
            end
            if app.CallingApp.UMACheckBox.Value
                app.CellsectoranglesCheckBox.Enable = 'On';
                app.CellsectoranglesCheckBox.Value = check_cell_angle;
            end
            app.FrequencyassignmentCheckBox.Value = check_freq;
            app.GenerationsEditField.Value = generations;
            app.PopulationEditField.Value = population;
        end

        % Button pushed function: ENTERButton
        function ENTERButtonPushed(app, event)
            app.CallingApp.Check_umiblind_opt = app.UMiBlindCoveragepositionsCheckBox.Value;
            app.CallingApp.Check_freq_opt = app.FrequencyassignmentCheckBox.Value;
            app.CallingApp.Check_cellangle_opt = app.CellsectoranglesCheckBox.Value;
            app.CallingApp.Opt_generations = app.GenerationsEditField.Value;
            app.CallingApp.Opt_population = app.PopulationEditField.Value;
            app.CallingApp.Square_edge_umi_opt = app.SquareedgesizeonlyforUMiBlindoptimizationEditField.Value;

            delete(app);
        end

        % Value changed function: UMiBlindCoveragepositionsCheckBox
        function UMiBlindCoveragepositionsCheckBoxValueChanged(app, event)
            value = app.UMiBlindCoveragepositionsCheckBox.Value;
            if value
                app.SquareedgesizeonlyforUMiBlindoptimizationEditField.Enable = 'On';
            else
                app.SquareedgesizeonlyforUMiBlindoptimizationEditField.Enable = 'Off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptimizationoptionsUIFigure = uifigure('Visible', 'off');
            app.OptimizationoptionsUIFigure.Name = 'Optimization options';
            app.GridLayout = uigridlayout(app.OptimizationoptionsUIFigure);
            app.ENTERButton = uibutton(app.GridLayout, 'push');
            app.ENTERButton.ButtonPushedFcn = createCallbackFcn(app, @ENTERButtonPushed, true);
            app.ENTERButton.Text = {'ENTER'; ''};
            app.ParametersandoptimizationoptionsPanel = uipanel(app.GridLayout);
            app.ParametersandoptimizationoptionsPanel.Title = 'Parameters and optimization options';
            app.GridLayout2 = uigridlayout(app.ParametersandoptimizationoptionsPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.UMiBlindCoveragepositionsCheckBox = uicheckbox(app.GridLayout3);
            app.UMiBlindCoveragepositionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UMiBlindCoveragepositionsCheckBoxValueChanged, true);
            app.UMiBlindCoveragepositionsCheckBox.Enable = 'off';
            app.UMiBlindCoveragepositionsCheckBox.Text = 'UMi Blind Coverage positions';
            app.FrequencyassignmentCheckBox = uicheckbox(app.GridLayout3);
            app.FrequencyassignmentCheckBox.Text = 'Frequency assignment';
            app.CellsectoranglesCheckBox = uicheckbox(app.GridLayout3);
            app.CellsectoranglesCheckBox.Enable = 'off';
            app.CellsectoranglesCheckBox.Text = 'Cell sector angles';
            app.ParameterstooptimizeLabel = uilabel(app.GridLayout2);
            app.ParameterstooptimizeLabel.Text = 'Parameters to optimize';
            app.GenerationsEditFieldLabel = uilabel(app.GridLayout2);
            app.GenerationsEditFieldLabel.Text = 'Generations';
            app.GenerationsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.GenerationsEditField.Limits = [0 Inf];
            app.GenerationsEditField.Value = 100;
            app.PopulationEditFieldLabel = uilabel(app.GridLayout2);
            app.PopulationEditFieldLabel.Text = 'Population';
            app.PopulationEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PopulationEditField.Limits = [0 Inf];
            app.PopulationEditField.Value = 30;
            app.SquareedgesizeonlyforUMiBlindoptimizationEditFieldLabel = uilabel(app.GridLayout2);
            app.SquareedgesizeonlyforUMiBlindoptimizationEditFieldLabel.Text = 'Square edge size (only for UMi Blind optimization)';
            app.SquareedgesizeonlyforUMiBlindoptimizationEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SquareedgesizeonlyforUMiBlindoptimizationEditField.Limits = [0 Inf];
            app.SquareedgesizeonlyforUMiBlindoptimizationEditField.Enable = 'off';
            app.SquareedgesizeonlyforUMiBlindoptimizationEditField.Value = 0.01;
            % Show the figure after all components are created
            app.OptimizationoptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optimization_configuration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptimizationoptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptimizationoptionsUIFigure)
        end
    end
end